classdef channelizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        AerospaceDefenseChannelizerDemoLabel  matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        Active                        matlab.ui.control.NumericEditField
        ActiveEditFieldLabel          matlab.ui.control.Label
        RunningLamp                   matlab.ui.control.Lamp
        RunningLampLabel              matlab.ui.control.Label
        SamplingFrequencySwitch       matlab.ui.control.Switch
        SamplingFrequencySwitchLabel  matlab.ui.control.Label
        ChannelsMKnob                 matlab.ui.control.DiscreteKnob
        ChannelsMKnobLabel            matlab.ui.control.Label
        ChannelizerInputsPanel        matlab.ui.container.Panel
        ChannelizerOutputsPanel       matlab.ui.container.Panel
        GoButton                      matlab.ui.control.Button
        OversamplingRatioButtonGroup  matlab.ui.container.ButtonGroup
        OSR_3                         matlab.ui.control.RadioButton
        OSR_2                         matlab.ui.control.RadioButton
        OSR_1                         matlab.ui.control.RadioButton
        OSR_0                         matlab.ui.control.RadioButton
        UIAxes_FreqDomain             matlab.ui.control.UIAxes
        UIAxes_ProtoFilter            matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        p % Channelizer parameters
        fs_Gsps
        Mchan
        Ntaps
        chan_id
        bw_trans_oneside
        sig_passband_ripple_dB
        sig_stopband_attenu_dB
        sig_passband
        sig_stopband
        Nframe
        Nfrac_bits
        OSR
        P
        Q
        oversamp_ratio
        Ncyclic
        Nshift
        fc_Msps
        fs_Msps
        Nsamp
        taps
        taps_sig
        tile_i_hdl
        tile_o_hdl
        chan_centers_GHz
    end
    
    methods (Access = private)

        function channelizer_run(app)           
            % Run channelizer:
            channelizer_simple(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function channelizer_setup(app)
            app.Active.Value = 5;
            app.RunningLampLabel.Text  = 'Stopped';
            channelizer_recompute(app);
        end

        % Callback function
        function OversamplingRatioButtonGroupButtonDown(app, event)

        end

        % Value changed function: ChannelsMKnob
        function ChannelsMKnobValueChanged(app, event)
            channelizer_recompute(app);
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            app.RunningLamp.Color = 'Green'; 
            app.RunningLampLabel.Text = 'Running'; pause(1);
            channelizer_recompute(app);
            channelizer_run(app);
            app.RunningLamp.Color = 'Red';
            app.RunningLampLabel.Text = 'Stopped';
        end

        % Value changed function: SamplingFrequencySwitch
        function SamplingFrequencySwitchValueChanged(app, event)
            channelizer_recompute(app);            
        end

        % Selection changed function: OversamplingRatioButtonGroup
        function OversamplingRatioButtonGroupSelectionChanged(app, event)
            channelizer_recompute(app);
        end

        % Callback function
        function KnobValueChanged(app, event)
            channelizer_recompute(app);
        end

        % Value changed function: Active
        function ActiveValueChanged(app, event)
            channelizer_recompute(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_ProtoFilter = uiaxes(app.UIFigure);
            title(app.UIAxes_ProtoFilter, 'Prototype Filter')
            app.UIAxes_FreqDomain = uiaxes(app.UIFigure);
            title(app.UIAxes_FreqDomain, 'Title')
            xlabel(app.UIAxes_FreqDomain, 'X')
            ylabel(app.UIAxes_FreqDomain, 'Y')
            zlabel(app.UIAxes_FreqDomain, 'Z')
            app.OversamplingRatioButtonGroup = uibuttongroup(app.UIFigure);
            app.OversamplingRatioButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OversamplingRatioButtonGroupSelectionChanged, true);
            app.OversamplingRatioButtonGroup.Title = 'Oversampling Ratio';
            app.OSR_0 = uiradiobutton(app.OversamplingRatioButtonGroup);
            app.OSR_0.Text = '1 / 1';
            app.OSR_0.Value = true;
            app.OSR_1 = uiradiobutton(app.OversamplingRatioButtonGroup);
            app.OSR_1.Text = '2 / 1';
            app.OSR_2 = uiradiobutton(app.OversamplingRatioButtonGroup);
            app.OSR_2.Text = '4 / 3';
            app.OSR_3 = uiradiobutton(app.OversamplingRatioButtonGroup);
            app.OSR_3.Text = '8 / 7';
            app.GoButton = uibutton(app.UIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Tooltip = {'Start simulation'};
            app.GoButton.Text = 'Go';
            app.ChannelizerOutputsPanel = uipanel(app.UIFigure);
            app.ChannelizerOutputsPanel.Title = 'Channelizer Outputs';
            app.ChannelizerInputsPanel = uipanel(app.UIFigure);
            app.ChannelizerInputsPanel.Title = 'Channelizer Inputs';
            app.ChannelsMKnobLabel = uilabel(app.UIFigure);
            app.ChannelsMKnobLabel.Text = '# Channels (M)';
            app.ChannelsMKnob = uiknob(app.UIFigure, 'discrete');
            app.ChannelsMKnob.Items = {'16', '32', '64', '128'};
            app.ChannelsMKnob.ItemsData = [16 32 64 128];
            app.ChannelsMKnob.ValueChangedFcn = createCallbackFcn(app, @ChannelsMKnobValueChanged, true);
            app.ChannelsMKnob.Value = 16;
            app.SamplingFrequencySwitchLabel = uilabel(app.UIFigure);
            app.SamplingFrequencySwitchLabel.Text = 'Sampling Frequency';
            app.SamplingFrequencySwitch = uiswitch(app.UIFigure, 'slider');
            app.SamplingFrequencySwitch.Items = {'10.5 Gsps', '20.5 Gsps'};
            app.SamplingFrequencySwitch.ItemsData = [10.5 20.5];
            app.SamplingFrequencySwitch.ValueChangedFcn = createCallbackFcn(app, @SamplingFrequencySwitchValueChanged, true);
            app.SamplingFrequencySwitch.Value = 10.5;
            app.RunningLampLabel = uilabel(app.UIFigure);
            app.RunningLampLabel.Text = 'Running';
            app.RunningLamp = uilamp(app.UIFigure);
            app.ActiveEditFieldLabel = uilabel(app.UIFigure);
            app.ActiveEditFieldLabel.Text = '# Active';
            app.Active = uieditfield(app.UIFigure, 'numeric');
            app.Active.Limits = [1 14];
            app.Active.RoundFractionalValues = 'on';
            app.Active.ValueChangedFcn = createCallbackFcn(app, @ActiveValueChanged, true);
            app.Active.Tooltip = {'Set # of active channels'};
            app.Active.Value = 1;
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'AMD_E_Wh_RGB.jpg');
            app.AerospaceDefenseChannelizerDemoLabel = uilabel(app.UIFigure);
            app.AerospaceDefenseChannelizerDemoLabel.Text = 'Aerospace & Defense Channelizer Demo';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = channelizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @channelizer_setup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end